classdef ChessRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        YourMoveTextAreaLabel  matlab.ui.control.Label
        YourMoveTextArea       matlab.ui.control.TextArea
        ExecuteMoveButton      matlab.ui.control.Button
        ReconsiderButton       matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        TestButton             matlab.ui.control.Button
        RobotKnobLabel         matlab.ui.control.Label
        RobotKnob              matlab.ui.control.DiscreteKnob
        DetectPiecesButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        MyChessBoard
        MyCBDisp
        MyRobot
    end
    
    methods (Access = private)
        
        function results = LocalizePieces(app)
            
             MyCamera = RealSensePointcloud();            
            
            
            % Take image, name "before_move" 
            [X,Y,Z] = MyCamera.getPointcloud(10, 0);
            MyRGB_Image = MyCamera.getRGBframe(10);
            
            % Take pawn and second picture "after_move"
            [X,Y,Z] = MyCamera.getPointcloud(10, 0);
            MyRGB_Image = MyCamera.getRGBframe(10);
            
            % remove all peieces and take picture "RGB_calibration"
            [X,Y,Z] = MyCamera.getPointcloud(10, 0);
            MyRGB_Image = MyCamera.getRGBframe(10);
            
            % set pawn to center and take picture "PC_calibration"
            [X,Y,Z] = MyCamera.getPointcloud(10, 0);
            MyRGB_Image = MyCamera.getRGBframe(10);
            
            MyCamera.Disconnect();
            
            load('pointcloud_test2.mat');
            
            MyPointCloudProc = PointCloudProcessing(X, Y, Z, 0);
            
            MyPointCloudProc.getValidIndex(0);
            
            MyPointCloudProc.Print(1);
            
            
            [MyPointCloudProc.ptCloud, MyPointCloudProc.loc] = MyPointCloudProc.createPointCloud();
            pcshow(MyPointCloudProc.ptCloud);
            
            [MyPointCloudProc.labels,MyPointCloudProc.numClusters] = MyPointCloudProc.getClusters();
            
            MyPointCloudProc.colorClusters();
            
            MyPointCloudProc.coors = MyPointCloudProc.getClusterCenter();
            MyPointCloudProc.fuseClusters();
            MyPointCloudProc.PrintClusterCenter();
            
            MyPointCloudProc.coors = MyPointCloudProc.getClusterCenter();
            MyPointCloudProc.fuseClusters();
            MyPointCloudProc.PrintClusterCenter();
            
            MyPointCloudProc.coors = MyPointCloudProc.getClusterCenter();
            MyPointCloudProc.fuseClusters();
            MyPointCloudProc.PrintClusterCenter();
            
            app.MyChessBoard.CompareDetectorWithBoard(MyPointCloudProc.coors, MyPointCloudProc.numClusters);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.MyCBDisp = DiplayChessboard(app.UIAxes);
            app.MyCBDisp.RefreshBoard();

            app.MyChessBoard = ChessBoard(app.MyCBDisp);
            app.MyChessBoard.Initialize();
            app.MyChessBoard.DisplayBoard();
            
                        
            % Mario: DEBUG REMOVe thi is to be replaced by a callibration procedure!
            Camera_board_offset = [0.024, 0.0034, 0.2503];
            app.MyChessBoard.SendPointCloudOffset(Camera_board_offset);
            
            app.MyRobot = ArmKinematics();
            app.MyRobot.StartUpRobot();
            %app.MyRobot.SuspendRobot();
            %app.RobotKnobValueChanged.value = "Suspend";
        end

        % Value changed function: RobotKnob
        function RobotKnobValueChanged(app, event)
            value = app.RobotKnob.Value;
            if strcmp(value, 'Suspend')
                app.MyRobot.SuspendRobot();
            end
            if strcmp(value, 'Off')
                app.MyRobot.PowerDownRobot();
            end
            if strcmp(value, 'On')
                app.MyRobot.StrobeLEDs();
                app.MyRobot.WakeUpFromSuspend();
            end
            if strcmp(value, 'Overwatch')
                app.MyRobot.SetOverwatch();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MyRobot.PowerDownRobot();
            app.MyRobot.Close();
            
            delete(app)
            
        end

        % Button pushed function: DetectPiecesButton
        function DetectPiecesButtonPushed(app, event)
            app.LocalizePieces();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YourMoveTextAreaLabel = uilabel(app.UIFigure);
            app.YourMoveTextAreaLabel.Text = 'YourMove:';
            app.YourMoveTextArea = uitextarea(app.UIFigure);
            app.ExecuteMoveButton = uibutton(app.UIFigure, 'push');
            app.ExecuteMoveButton.Text = 'Execute Move';
            app.ReconsiderButton = uibutton(app.UIFigure, 'push');
            app.ReconsiderButton.Text = 'Reconsider';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.Text = 'Test';
            app.RobotKnobLabel = uilabel(app.UIFigure);
            app.RobotKnobLabel.Text = 'Robot';
            app.RobotKnob = uiknob(app.UIFigure, 'discrete');
            app.RobotKnob.Items = {'Off', 'Suspend', 'On', 'Overwatch'};
            app.RobotKnob.ValueChangedFcn = createCallbackFcn(app, @RobotKnobValueChanged, true);
            app.DetectPiecesButton = uibutton(app.UIFigure, 'push');
            app.DetectPiecesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectPiecesButtonPushed, true);
            app.DetectPiecesButton.Text = 'Detect Pieces';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChessRobot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end