classdef MyChessApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        DetectDifferenceButton   matlab.ui.control.Button
        SetInitialButton         matlab.ui.control.Button
        FileChoicesListBoxLabel  matlab.ui.control.Label
        FileChoicesListBox       matlab.ui.control.ListBox
        SetFolderButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        MyDisp           % Chessboard Display Class
        MyChessBoard     % Chessboard state class
        CurrentBoardData % Currently loaded PointCloud
    end
    
    methods (Access = private)
        
        function InitializeList(app, SavedBoardsPath)
            matFilesInfo = dir(fullfile(SavedBoardsPath, '*.mat'));
            app.FileChoicesListBox.Items = {matFilesInfo.name};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MyDisp = DiplayChessboard(app.UIAxes);
            app.MyDisp.RefreshBoard();
            
            app.MyChessBoard = ChessBoard(app.MyDisp);
            app.MyChessBoard.Initialize();
            app.MyChessBoard.DisplayBoard();
        end

        % Button pushed function: SetFolderButton
        function SetFolderButtonPushed(app, event)
            SavedBoardsPath = uigetdir();
            app.InitializeList(SavedBoardsPath);
        end

        % Button pushed function: SetInitialButton
        function SetInitialButtonPushed(app, event)
            app.CurrentBoardData = load(app.FileChoicesListBox.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DetectDifferenceButton = uibutton(app.UIFigure, 'push');
            app.DetectDifferenceButton.Text = 'Detect Difference';
            app.SetInitialButton = uibutton(app.UIFigure, 'push');
            app.SetInitialButton.ButtonPushedFcn = createCallbackFcn(app, @SetInitialButtonPushed, true);
            app.SetInitialButton.Text = 'Set Initial';
            app.FileChoicesListBoxLabel = uilabel(app.UIFigure);
            app.FileChoicesListBoxLabel.Text = 'File Choices';
            app.FileChoicesListBox = uilistbox(app.UIFigure);
            app.FileChoicesListBox.Items = {};
            app.FileChoicesListBox.Value = {};
            app.SetFolderButton = uibutton(app.UIFigure, 'push');
            app.SetFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetFolderButtonPushed, true);
            app.SetFolderButton.Text = 'Set Folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyChessApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end